classdef noSettingsProcessGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        checkbox_applytoall  matlab.ui.control.CheckBox
        pushbutton_done      matlab.ui.control.Button
        pushbutton_cancel    matlab.ui.control.Button
        text_processName     matlab.ui.control.Label
        text_copyright       matlab.ui.control.Label
        uipanel_1            matlab.ui.container.ButtonGroup
        text34               matlab.ui.control.Label
        axes_help            matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.figure1.DeleteFcn = createCallbackFcn(app, @figure1_DeleteFcn, true);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function noSettingsProcessGUI_OpeningFcn(app, varargin)
            % --- Executes just before noSettingsProcessGUI is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            processGUI_OpeningFcn(hObject, eventdata, handles, varargin{:});
            
            % Choose default command line output for noSettingsProcessGUI
            handles.output = hObject;
            
            guidata(hObject, handles);
        end

        % Callback function
        function figure1_DeleteFcn(app, event)
            % --- Executes during object deletion, before destroying properties.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Notify the package GUI that the setting panel is closed
            userData = get(handles.figure1, 'UserData');
            
            if isfield(userData, 'helpFig') && ishandle(userData.helpFig)
               delete(userData.helpFig)
            end
            
            set(handles.figure1, 'UserData', userData);
            guidata(hObject,handles);
        end

        % Key press function: figure1
        function figure1_KeyPressFcn(app, event)
            % --- Executes on key press with focus on figure1 and none of its controls.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if strcmp(eventdata.Key, 'return')
                pushbutton_done_Callback(handles.pushbutton_done, [], handles);
            end
        end

        % Button pushed function: pushbutton_cancel
        function pushbutton_cancel_Callback(app, event)
            % --- Executes on button press in pushbutton_cancel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Delete figure
            delete(handles.figure1);
        end

        % Button pushed function: pushbutton_done
        function pushbutton_done_Callback(app, event)
            % --- Executes on button press in pushbutton_done.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % Process Sanity check ( only check underlying data )
            userData = get(handles.figure1, 'UserData');
            try
                userData.crtProc.sanityCheck;
            catch ME
            
                errordlg([ME.message 'Please double check your data.'],...
                            'Setting Error','modal');
                return;
            end
            
            funParams = [];
            
            % Set parameters
            processGUI_ApplyFcn(hObject, eventdata, handles,funParams);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Setting - Thresholding';
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1_KeyPressFcn, true);
            app.figure1.Tag = 'figure1';
            app.axes_help = uiaxes(app.figure1);
            app.axes_help.Tag = 'axes_help';
            app.axes_help.Visible = 'off';
            app.uipanel_1 = uibuttongroup(app.figure1);
            app.uipanel_1.Title = 'Parameters';
            app.uipanel_1.Tag = 'uipanel_1';
            app.text34 = uilabel(app.uipanel_1);
            app.text34.Tag = 'text34';
            app.text34.Text = 'There is currently no parameter to set.';
            app.text_copyright = uilabel(app.figure1);
            app.text_copyright.Tag = 'text_copyright';
            app.text_copyright.Text = 'copyright info';
            app.text_processName = uilabel(app.figure1);
            app.text_processName.Tag = 'text_processName';
            app.text_processName.Text = 'Step n: process nane';
            app.pushbutton_cancel = uibutton(app.figure1, 'push');
            app.pushbutton_cancel.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_cancel_Callback, true);
            app.pushbutton_cancel.Tag = 'pushbutton_cancel';
            app.pushbutton_cancel.Text = 'Cancel';
            app.pushbutton_done = uibutton(app.figure1, 'push');
            app.pushbutton_done.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_done_Callback, true);
            app.pushbutton_done.Tag = 'pushbutton_done';
            app.pushbutton_done.Text = 'Apply';
            app.checkbox_applytoall = uicheckbox(app.figure1);
            app.checkbox_applytoall.Tag = 'checkbox_applytoall';
            app.checkbox_applytoall.Text = 'Apply settings to all movies';
            app.checkbox_applytoall.Value = true;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = noSettingsProcessGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)noSettingsProcessGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end